classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        sendBtn     matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        sendInfo    matlab.ui.control.TextArea
        recorder    matlab.ui.control.StateButton
        Label_3     matlab.ui.control.Label
        Gauge       matlab.ui.control.NinetyDegreeGauge
        AButton     matlab.ui.control.Button
        BButton     matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        Label       matlab.ui.control.Label
        recvInfo    matlab.ui.control.TextArea
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myRecorder  audiorecorder; % 录音
        t timer; % 录音计时器
        isFindingStart true;  % 是否在寻找开头
        isdemoding false; % 是否正在解析
        pStart int; % 当前包的开头点
    end
    
    properties (Access = private)
        f0 = 4000; % Description
        f1 = 6000; % Description
        duration = 0.025; % Description
        Fs = 48000; % Description
    end
    
    methods (Access = private)
        
        function recorderTimerFcn(app)
            myRecording = getaudiodata(app.myRecorder);
            plot(app.UIAxes, myRecording(end-100:end));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: recorder
        function recorderValueChanged(app, event)
            
            value = app.recorder.Value;
            if  value == 1
                app.t= timer('Period',0.1,'ExecutionMode',"fixedRate");
                app.myRecorder = audiorecorder(48000, 24, 1);
                record(app.myRecorder);
                app.t.StartDelay = 0.6;
                app.t.Period = 0.1;
                app.t.TimerFcn=@(~,~)recorderTF(app);
                start(app.t);
                
            elseif  value == 0  
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                play(app.myRecorder);
                data = getaudiodata(app.myRecorder);
                sound(data, app.Fs);
                [code, fft_diff] = my_FSK_demod(data, app.Fs, app.duration, app.f0, app.f1 );
                plot(fft_diff);
                app.recvInfo.Value = int2str(code);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.sendBtn = uibutton(app.LeftPanel, 'push');
            app.sendBtn.Text = '发送';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '发送信号';
            app.sendInfo = uitextarea(app.LeftPanel);
            app.recorder = uibutton(app.LeftPanel, 'state');
            app.recorder.ValueChangedFcn = createCallbackFcn(app, @recorderValueChanged, true);
            app.recorder.Text = '开始录音';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '信号强度';
            app.Gauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.AButton = uibutton(app.LeftPanel, 'push');
            app.AButton.Text = '测距A';
            app.BButton = uibutton(app.LeftPanel, 'push');
            app.BButton.Text = '测距B';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '接收信号';
            app.recvInfo = uitextarea(app.RightPanel);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end